classdef fuel_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FUELQUANTITYINLITRESLabel  matlab.ui.control.Label
        FuelquantityInput          matlab.ui.control.NumericEditField
        PRICEINKRONERLabel         matlab.ui.control.Label
        PriceInput                 matlab.ui.control.NumericEditField
        FuelPriceSelectionList     matlab.ui.control.ListBox
        OKButton                   matlab.ui.control.Button
        FuelSelectedPriceOutput    matlab.ui.control.NumericEditField
        AMOUNTTOBEPAIDLabel        matlab.ui.control.Label
        AmountTobePaidOutput       matlab.ui.control.NumericEditField
        PricesTable                matlab.ui.control.Table
        Header                     matlab.ui.control.Label
        NewCustomerButton          matlab.ui.control.Button
        PAIDButton                 matlab.ui.control.Button
        FILLButton                 matlab.ui.control.Button
        CANCELButton               matlab.ui.control.Button
        infoLabel                  matlab.ui.control.Label
        queryBox                   matlab.ui.control.Label
        fuelPriceDisplay           matlab.ui.control.Label
        FuelPricesLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        fill = 0;
        totalFuel = 0;
        prices=[11.2;13.8;14];
        fuelprices=[];
        proceedToPay = false;
        retVal=false;
        payment=false;
        fuelSelected = false;
        newCustomer = false;
    end
    
    methods (Access = private)
     % Function to reset the values   
        function retVal = resetValues(app)
            app.newCustomer=false;
            app.FuelPriceSelectionList.Value = 'Select';
            app.FuelSelectedPriceOutput.Value=0;
            app.FuelquantityInput.Value=0;
            app.PriceInput.Value=0;
            app.AmountTobePaidOutput.Value=0;
            app.fill=0;
            app.totalFuel=0;
            app.proceedToPay=false;
            app.payment=false;
            app.fuelSelected=false;
            retVal=true;
        end
      % OK button fuctionality  
        function OKbutton(app)
            if app.PriceInput.Value > 0 && app.fuelSelected==true && app.newCustomer == true
                app.FuelquantityInput.Value=app.PriceInput.Value/app.FuelSelectedPriceOutput.Value;
                app.AmountTobePaidOutput.Value = app.PriceInput.Value;
                app.proceedToPay=true;
            elseif app.FuelquantityInput.Value > 0 && app.fuelSelected==true && app.newCustomer == true
                app.AmountTobePaidOutput.Value=app.FuelquantityInput.Value*app.FuelSelectedPriceOutput.Value;
                app.PriceInput.Value = app.AmountTobePaidOutput.Value;
                app.proceedToPay=true;
            elseif app.fuelSelected == false && app.newCustomer == true
                wait(app,2)
                app.infoLabel.Text='Please select a fuel!';
                return
            elseif app.newCustomer == false
                newCustWarning(app);
            else
                app.infoLabel.Text = 'Please enter a valid input';
                return
            end
            app.fill = 0;
            app.totalFuel = app.FuelquantityInput.Value;
            app.infoLabel.Text = 'Please pay the amount by card and press PAID';
        end
     
      % PAID button functionality
        function PAIDbutton(app)
            if app.proceedToPay==true
                wait(app,1)
                app.infoLabel.Text='AVOID SPILLAGE';
                pause(1)
                app.infoLabel.Text='Check for Zero in the meter';
                pause(2)
                wait(app,3)
                app.infoLabel.Text='Please hold the pipe into the tank of your vehicle and press FILL';
                app.payment=true;
                app.PriceInput.Value = 0;
                app.FuelquantityInput.Value = 0;
            elseif app.newCustomer == false
                newCustWarning(app);
            else
                app.infoLabel.Text='Please type the quantity or the price of the fuel and press OK';
            end
        end
     % FILL button fuctionality   
        function fillButton(app)
            if app.newCustomer == true
                if (app.totalFuel-app.fill)<0.25 && (app.totalFuel-app.fill)>0
                    app.fill = app.totalFuel;
                    app.PriceInput.Value = app.fill * app.FuelSelectedPriceOutput.Value;
                    app.FuelquantityInput.Value = app.fill;
                elseif app.fill < app.totalFuel && app.payment == true
                    app.fill=app.fill+0.25;
                    app.PriceInput.Value = app.fill * app.FuelSelectedPriceOutput.Value;
                    app.FuelquantityInput.Value = app.fill;
                elseif app.fill == app.totalFuel && app.payment == true
                    app.infoLabel.Text='Refill done!';
                    pause(2)
                    app.infoLabel.Text='Thank you! Visit again';
                    pause(2)
                    startupFcn(app)
                    return
                elseif app.payment == false && app.proceedToPay==false
                    wait(app,2)
                    app.infoLabel.Text='Please type the quantity or the price of the fuel and press OK';
                elseif app.payment == false && app.proceedToPay==true
                    app.infoLabel.Text='Payment pending! Please pay the amount by card and press PAID';
                end
            else
                newCustWarning(app)
            end
        end
      % Warning message to begin the process  
        function newCustWarning(app)
            wait(app,2)
            app.infoLabel.Text = 'Start the process by pressing the NEW CUSTOMER button!';
            pause(2)
            startupFcn(app)
            return
        end
     % Instruction to wait if there is a mistake in following the process   
        function wait(app,val)
            if val == 1
                app.queryBox.FontColor=[0 0 0];
                app.queryBox.Text = 'Please wait...';
            elseif val == 2
                app.queryBox.FontColor=[0 0 0];
                app.queryBox.Text = 'Follow the Instructions...';
            elseif val == 3
                app.queryBox.FontColor=[1 0 0];
                app.queryBox.Text = 'For queries and complaints please contact help@easyfuel.com';
            elseif val == 4
                app.queryBox.FontColor=[1 0 0];
                app.queryBox.Text ='CANT BEGIN A NEW PROCESS IN THE MIDDLE OF ANOTHER PROCESS';
            elseif val == 5
                app.queryBox.FontColor=[0 0 0];
                app.queryBox.Text ='Terminate the process by pressing CANCEL and contact us for refund ';
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.retVal = resetValues(app);
            date = datestr(now, 'dd/mm/yy-HH:MM');
            app.fuelPriceDisplay.Text=date;
            app.fuelprices(1)=app.prices(1) + 0.25 * rand;
            app.fuelprices(2)=app.prices(2) + 0.25 * rand;
            app.fuelprices(3)=app.prices(3) + 0.25 * rand;
            app.PricesTable.Data={app.fuelprices(1), app.fuelprices(2), app.fuelprices(3)};
            wait(app,3)
            app.infoLabel.Text='Welcome to Easy Fuel station';
        end

        % Value changed function: FuelPriceSelectionList
        function FuelPriceSelectionListValueChanged(app, event)
            value = app.FuelPriceSelectionList.Value;
            if app.newCustomer == true
                if value== 'E-85'
                    price=app.fuelprices(1);
                    app.fuelSelected=true;
                elseif value == 'E-95'
                    price=app.fuelprices(2);
                    app.fuelSelected=true;
                elseif value == 'D'
                    price=app.fuelprices(3);
                    app.fuelSelected=true;
                elseif value == 'Select'
                    price=0;
                    app.fuelSelected=false;
                end
                app.infoLabel.Text='Please type the quantity or the price of the fuel and press OK';
                app.FuelSelectedPriceOutput.Value=price;
            else
                newCustWarning(app);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            OKbutton(app);
        end

        % Button pushed function: NewCustomerButton
        function NewCustomerButtonPushed(app, event)
            if app.payment==true
                wait(app,4)
                app.infoLabel.Text = ' '
                pause(3)
                wait(app,5)
                app.infoLabel.Text = 'at help@easyfuel.com or press PAID if you wish to continue the process'
            else
                startupFcn(app);
                app.infoLabel.Text='Please select the type of fuel below';
                app.newCustomer=true;
            end
        end

        % Button pushed function: PAIDButton
        function PAIDButtonPushed(app, event)
            PAIDbutton(app);
        end

        % Button pushed function: FILLButton
        function FILLButtonPushed(app, event)
            fillButton(app);
        end

        % Button pushed function: CANCELButton
        function CANCELButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FUELQUANTITYINLITRESLabel = uilabel(app.UIFigure);
            app.FUELQUANTITYINLITRESLabel.Text = {'FUEL QUANTITY'; '(IN LITRES)'};
            app.FuelquantityInput = uieditfield(app.UIFigure, 'numeric');
            app.FuelquantityInput.Tag = '(in Litres)';
            app.PRICEINKRONERLabel = uilabel(app.UIFigure);
            app.PRICEINKRONERLabel.Text = {'PRICE'; '(IN KRONER)'};
            app.PriceInput = uieditfield(app.UIFigure, 'numeric');
            app.FuelPriceSelectionList = uilistbox(app.UIFigure);
            app.FuelPriceSelectionList.Items = {'Select', 'E-85', 'E-95', 'D'};
            app.FuelPriceSelectionList.ValueChangedFcn = createCallbackFcn(app, @FuelPriceSelectionListValueChanged, true);
            app.FuelPriceSelectionList.Value = 'Select';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FuelSelectedPriceOutput = uieditfield(app.UIFigure, 'numeric');
            app.AMOUNTTOBEPAIDLabel = uilabel(app.UIFigure);
            app.AMOUNTTOBEPAIDLabel.Text = 'AMOUNT TO BE PAID';
            app.AmountTobePaidOutput = uieditfield(app.UIFigure, 'numeric');
            app.PricesTable = uitable(app.UIFigure);
            app.PricesTable.ColumnName = {'E-85'; 'E-95'; 'D'};
            app.PricesTable.RowName = {};
            app.Header = uilabel(app.UIFigure);
            app.Header.Text = 'EASY FUEL STATION';
            app.NewCustomerButton = uibutton(app.UIFigure, 'push');
            app.NewCustomerButton.ButtonPushedFcn = createCallbackFcn(app, @NewCustomerButtonPushed, true);
            app.NewCustomerButton.Text = {'New'; 'Customer'};
            app.PAIDButton = uibutton(app.UIFigure, 'push');
            app.PAIDButton.ButtonPushedFcn = createCallbackFcn(app, @PAIDButtonPushed, true);
            app.PAIDButton.Text = 'PAID';
            app.FILLButton = uibutton(app.UIFigure, 'push');
            app.FILLButton.ButtonPushedFcn = createCallbackFcn(app, @FILLButtonPushed, true);
            app.FILLButton.Text = 'FILL';
            app.CANCELButton = uibutton(app.UIFigure, 'push');
            app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton.Text = 'CANCEL';
            app.infoLabel = uilabel(app.UIFigure);
            app.infoLabel.Text = '';
            app.queryBox = uilabel(app.UIFigure);
            app.queryBox.Text = 'For queries and complaints please contact help@easyfuel.com';
            app.fuelPriceDisplay = uilabel(app.UIFigure);
            app.fuelPriceDisplay.Text = '';
            app.FuelPricesLabel = uilabel(app.UIFigure);
            app.FuelPricesLabel.Text = 'Fuel Prices as on : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuel_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end